classdef Function_analysis_interface < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Roots             matlab.ui.control.CheckBox
        Extremes          matlab.ui.control.CheckBox
        Infection_points  matlab.ui.control.CheckBox
        MATLABLabel       matlab.ui.control.Label
        Function          matlab.ui.control.EditField
        Label_2           matlab.ui.control.Label
        From              matlab.ui.control.NumericEditField
        Label_3           matlab.ui.control.Label
        To                matlab.ui.control.NumericEditField
        Interval          matlab.ui.control.Label
        Analysis          matlab.ui.control.Button
        Y                 matlab.ui.control.Label
        Label_4           matlab.ui.control.Label
        Answer            matlab.ui.control.EditField
        UIAxes            matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Analysis
        function AnalysisButtonPushed(app, event)
            app.Answer.Value="";
            syms x;            
            interval_from = app.From.Value;
            interval_to = app.To.Value;
            fff = app.Function.Value;
            fplot(app.UIAxes, fff, [interval_from interval_to], "Color","k")
            hold(app.UIAxes, "on");

            f = str2sym(app.Function.Value);
            root = app.Roots.Value;
            extr = app.Extremes.Value;
            inf_point = app.Infection_points.Value;


            %A = solve(f,x);
            %----------
            k = 0;
            for i=interval_from:interval_to
                k=k+1;
                A(k)=fzero(fff,i);
            end
            A = A((A<interval_to)&(interval_from<A))
            q(1) = A(1);
            k=1;
            for i=2:length(A)
                if (abs(A(i)-A(i-1))>0.01)
                    k=k+1;
                    q(k)=A(i)
                end
            end
            %-------------
            %a = double(A);
            %Aa = num2str(a');
            Aa = num2str(q);
            points_1 = plot(app.UIAxes, q, subs(f,x,q),'r*');
          hold (app.UIAxes,'on')
            if (root==false)
                delete(points_1);
            else app.Answer.Value = app.Answer.Value + "Корни: " + Aa + ";" + "  ";
            end
            
            
            g = diff(f);
            %B = solve(g,x);
            %-----------------------
            k = 0;
            ggg=arrayfun(@string, g);
            for i=interval_from:interval_to
                k=k+1;
                B(k)=fzero(ggg,i);
            end
            B = B((B<interval_to)&(interval_from<B))
            w(1) = B(1);
            k=1;
            for i=2:length(B)
                if (abs(B(i)-B(i-1))>0.01)
                    k=k+1;
                    w(k)=B(i)
                end
            end
            %-----------------------
            %b = double(B);
            %Bb = num2str(b');
            Bb = num2str(w);
            points_2 = plot(app.UIAxes, w, subs(f,x,w),'g*')
          hold (app.UIAxes,'on')    
            if (extr==false)
                delete(points_2);
            else app.Answer.Value = app.Answer.Value +"Экстремумы: "+ Bb + ";" + "  ";
            end
            
            
                d = diff(g);
                %C = solve(d,x);
                %-----------------------------
                k = 0;
                ddd=arrayfun(@string, d);
                for i=interval_from:interval_to
                    k=k+1;
                    C(k)=fzero(ddd,i);
                end
                C = C((C<interval_to)&(interval_from<C))
                v(1) = C(1);
                k=1;
                for i=2:length(C)
                    if (abs(C(i)-C(i-1))>0.01)
                        k=k+1;
                        v(k)=C(i)
                    end
                end
                %-----------------------------
                %c = double(C);
                %Cc = num2str(c');
                Cc = num2str(v);
            points_3 = plot(app.UIAxes, v, subs(f,x,v),'b*')
          hold (app.UIAxes,'on')    
            if (inf_point==false)
                delete(points_3);
            else app.Answer.Value = app.Answer.Value + "Точки перегиба:" + Cc + ";" + "  ";
            end
            hold (app.UIAxes,'off')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Roots = uicheckbox(app.UIFigure);
            app.Roots.Text = 'Корни';
            app.Extremes = uicheckbox(app.UIFigure);
            app.Extremes.Text = 'Экстремумы';
            app.Infection_points = uicheckbox(app.UIFigure);
            app.Infection_points.Text = 'Точки перегиба';
            app.MATLABLabel = uilabel(app.UIFigure);
            app.MATLABLabel.Text = {'Введите свою функцию'; '(Пожалуйста, используйте '; 'терминологию MATLAB):'};
            app.Function = uieditfield(app.UIFigure, 'text');
            app.Function.Value = 'exp(-x).*sin(x)';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'ОТ';
            app.From = uieditfield(app.UIFigure, 'numeric');
            app.From.Value = -5;
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'ДО';
            app.To = uieditfield(app.UIFigure, 'numeric');
            app.To.Value = 5;
            app.Interval = uilabel(app.UIFigure);
            app.Interval.Text = {'Введите интервал '; 'на котором вы хотите '; 'построить график:'};
            app.Analysis = uibutton(app.UIFigure, 'push');
            app.Analysis.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.Analysis.Text = 'Анализ';
            app.Y = uilabel(app.UIFigure);
            app.Y.Text = 'Y = ';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = {'Отметьте, что вы хотите найти '; 'и нажмите "Анализ".'};
            app.Answer = uieditfield(app.UIFigure, 'text');
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ваша Функция')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Function_analysis_interface

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end