classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Subjects            matlab.ui.control.Label
        points              matlab.ui.control.Label
        Label               matlab.ui.control.Label
        Second_name         matlab.ui.control.EditField
        Label_2             matlab.ui.control.Label
        Name                matlab.ui.control.EditField
        Label_3             matlab.ui.control.Label
        Middle_name         matlab.ui.control.EditField
        FIO                 matlab.ui.control.Label
        Label_4             matlab.ui.control.Label
        Subject_1           matlab.ui.control.EditField
        EditField_2Label    matlab.ui.control.Label
        Subject_2           matlab.ui.control.EditField
        EditField_3Label    matlab.ui.control.Label
        Subject_3           matlab.ui.control.EditField
        Label_5             matlab.ui.control.Label
        Points_1            matlab.ui.control.NumericEditField
        EditField_2Label_2  matlab.ui.control.Label
        Points_2            matlab.ui.control.NumericEditField
        EditField_3Label_2  matlab.ui.control.Label
        Points_3            matlab.ui.control.NumericEditField
        Passage_points      matlab.ui.control.Label
        Points_total        matlab.ui.control.Label
        Button              matlab.ui.control.StateButton
        Label_6             matlab.ui.control.Label
        sum                 matlab.ui.control.TextArea
        Label_7             matlab.ui.control.Label
        Answer              matlab.ui.control.TextArea
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            Pr1 = app.Points_1.Value;
            Pr2 = app.Points_2.Value;
            Pr3 = app.Points_3.Value;
            if (Pr1<0) || (Pr1>100) || (Pr2<0) || (Pr3>100) || (Pr3<0) || (Pr3>100)
                errordlg("Введите числа от 0 до 100")
            else
                SUM = Pr1 + Pr2 + Pr3;
                app.sum.Value = num2str(SUM)
                if (SUM>=250)
                    app.Answer.Value = "Поздравляем!"
                else app.Answer.Value = "Здравствуй, небо в облаках! Здравствуй, юность в сапогах"
                end
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Subjects = uilabel(app.UIFigure);
            app.Subjects.Text = 'Предметы';
            app.points = uilabel(app.UIFigure);
            app.points.Text = 'Баллы за ЕГЭ';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Фамилия:';
            app.Second_name = uieditfield(app.UIFigure, 'text');
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Имя:';
            app.Name = uieditfield(app.UIFigure, 'text');
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Отчество:';
            app.Middle_name = uieditfield(app.UIFigure, 'text');
            app.FIO = uilabel(app.UIFigure);
            app.FIO.Text = 'ФИО';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '';
            app.Subject_1 = uieditfield(app.UIFigure, 'text');
            app.EditField_2Label = uilabel(app.UIFigure);
            app.EditField_2Label.Text = '';
            app.Subject_2 = uieditfield(app.UIFigure, 'text');
            app.EditField_3Label = uilabel(app.UIFigure);
            app.EditField_3Label.Text = '';
            app.Subject_3 = uieditfield(app.UIFigure, 'text');
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '';
            app.Points_1 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_2Label_2 = uilabel(app.UIFigure);
            app.EditField_2Label_2.Text = '';
            app.Points_2 = uieditfield(app.UIFigure, 'numeric');
            app.EditField_3Label_2 = uilabel(app.UIFigure);
            app.EditField_3Label_2.Text = '';
            app.Points_3 = uieditfield(app.UIFigure, 'numeric');
            app.Passage_points = uilabel(app.UIFigure);
            app.Passage_points.Text = 'Проходной балл: 250';
            app.Points_total = uilabel(app.UIFigure);
            app.Points_total.Text = 'Сумма баллов:';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Вычисление';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '';
            app.sum = uitextarea(app.UIFigure);
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '';
            app.Answer = uitextarea(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end