classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure      matlab.ui.Figure
        CheckBox_Sin  matlab.ui.control.CheckBox
        CheckBox_Cos  matlab.ui.control.CheckBox
        Button        matlab.ui.control.StateButton
        Label         matlab.ui.control.Label
        UIAxes        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: CheckBox_Sin
        function CheckBox_SinValueChanged(app, event)

        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)

        end

        % Value changed function: CheckBox_Cos
        function CheckBox_CosValueChanged(app, event)

        end

        % Value changed function: Button
        function ButtonValueChanged(app, event)
            value2 = app.CheckBox_Cos.Value;
            value1 = app.CheckBox_Sin.Value;
                x2 = [0:0.1:4*pi];
                y2 = cos(x2);
                x1 = [0:0.1:4*pi];
                y1 = sin(x1);
                
            if (value2 == true) && (value1 == false)
                plot(app.UIAxes,x2,y2);
            end
            
            if (value1 == true) && (value2 == false)
                plot(app.UIAxes,x1,y1);
            end
            
            if (value1 == false) && (value2 == false)
                cla(app.UIAxes)
            end
            
            if (value1 == true) && (value2 == true)
                plot(app.UIAxes,x2,y2,x1,y1)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckBox_Sin = uicheckbox(app.UIFigure);
            app.CheckBox_Sin.ValueChangedFcn = createCallbackFcn(app, @CheckBox_SinValueChanged, true);
            app.CheckBox_Sin.Text = 'График синуса';
            app.CheckBox_Cos = uicheckbox(app.UIFigure);
            app.CheckBox_Cos.ValueChangedFcn = createCallbackFcn(app, @CheckBox_CosValueChanged, true);
            app.CheckBox_Cos.Text = 'График косинуса';
            app.Button = uibutton(app.UIFigure, 'state');
            app.Button.ValueChangedFcn = createCallbackFcn(app, @ButtonValueChanged, true);
            app.Button.Text = 'Вычисление';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Группа переключателей';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'График')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 15];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes.XTick = [0 5 10 15];
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'};
            app.UIAxes.YTick = [-1 -0.8 -0.6 -0.4 -0.2 0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.YTickLabel = {'-1'; '-0.8'; '-0.6'; '-0.4'; '-0.2'; '0'; '0.2'; '0.4'; '0.6'; '0.8'; '1'};
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end